classdef injection_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InjectionControlAppUIFigure  matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ControlTab                   matlab.ui.container.Tab
        GoHomeButton                 matlab.ui.control.Button
        SeekButton                   matlab.ui.control.Button
        RobotOrientationPanel        matlab.ui.container.Panel
        ArduinoConnectionKinematicStatusPanel  matlab.ui.container.Panel
        ArmLamp                      matlab.ui.control.Lamp
        ArmLampLabel                 matlab.ui.control.Label
        PortLamp                     matlab.ui.control.Lamp
        PortLampLabel                matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        ConnectButton                matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        SerialDataTextArea           matlab.ui.control.TextArea
        SerialDataTextAreaLabel      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        ConfigureTab                 matlab.ui.container.Tab
        MotorConfiguratorPanel       matlab.ui.container.Panel
        SubmitButton                 matlab.ui.control.Button
        Label_8                      matlab.ui.control.Label
        MaxPulseWidthCheckBox_3      matlab.ui.control.CheckBox
        Label_7                      matlab.ui.control.Label
        MinPulseWidthCheckBox_3      matlab.ui.control.CheckBox
        Label_6                      matlab.ui.control.Label
        MaxPulseWidthCheckBox_2      matlab.ui.control.CheckBox
        Label_5                      matlab.ui.control.Label
        MinPulseWidthCheckBox_2      matlab.ui.control.CheckBox
        Label_4                      matlab.ui.control.Label
        MaxPulseWidthCheckBox        matlab.ui.control.CheckBox
        Label_3                      matlab.ui.control.Label
        MinPulseWidthCheckBox        matlab.ui.control.CheckBox
        q3PulseWidthSpinner          matlab.ui.control.Spinner
        q3PulseWidthSpinnerLabel     matlab.ui.control.Label
        q2PulseWidthSpinner          matlab.ui.control.Spinner
        q2PulseWidthSpinnerLabel     matlab.ui.control.Label
        q1PulseWidthSpinner          matlab.ui.control.Spinner
        q1PulseWidthSpinnerLabel     matlab.ui.control.Label
        Switch                       matlab.ui.control.Switch
        ArduinoConfigurationPanel    matlab.ui.container.Panel
        TrigPinEditField             matlab.ui.control.NumericEditField
        TrigPinEditFieldLabel        matlab.ui.control.Label
        EchoPinEditField             matlab.ui.control.NumericEditField
        EchoPinEditFieldLabel        matlab.ui.control.Label
        EnterButton_2                matlab.ui.control.Button
        BaudRateEditField            matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel       matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        ArmConfigurationPanel        matlab.ui.container.Panel
        q3EditField                  matlab.ui.control.NumericEditField
        q3EditFieldLabel             matlab.ui.control.Label
        q2EditField                  matlab.ui.control.NumericEditField
        q2EditFieldLabel             matlab.ui.control.Label
        q1EditField                  matlab.ui.control.NumericEditField
        q1EditFieldLabel             matlab.ui.control.Label
        HomePositiondegLabel         matlab.ui.control.Label
        EnterButton                  matlab.ui.control.Button
        MaxEditField_3               matlab.ui.control.NumericEditField
        MaxEditField_3Label          matlab.ui.control.Label
        MinEditField_3               matlab.ui.control.NumericEditField
        MinEditField_3Label          matlab.ui.control.Label
        MaxEditField_2               matlab.ui.control.NumericEditField
        MaxEditField_2Label          matlab.ui.control.Label
        MinEditField_2               matlab.ui.control.NumericEditField
        MinEditField_2Label          matlab.ui.control.Label
        MaxEditField                 matlab.ui.control.NumericEditField
        MaxEditFieldLabel            matlab.ui.control.Label
        MinEditField                 matlab.ui.control.NumericEditField
        MinEditFieldLabel            matlab.ui.control.Label
        Link3L3EditField             matlab.ui.control.NumericEditField
        Link3L3EditFieldLabel        matlab.ui.control.Label
        Link2L2EditField             matlab.ui.control.NumericEditField
        Link2L2EditFieldLabel        matlab.ui.control.Label
        Link1d1EditField             matlab.ui.control.NumericEditField
        Link1d1EditFieldLabel        matlab.ui.control.Label
        q3Label                      matlab.ui.control.Label
        q2Label                      matlab.ui.control.Label
        q1Label                      matlab.ui.control.Label
        JointAngleLimitsdegLabel     matlab.ui.control.Label
        LinkLengthsmmLabel           matlab.ui.control.Label
        ArmLayoutVariablesandConventionsLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    properties (Access = private)
        %User Submitted Data / Initialized Data for Analysis of Arm, Workspace, and Arduino
        d %Link 1 length
        l2 %Link 2 length
        l3 %Link 3 length
        tlim1 %Joint 1 range
        tlim2 %Joint 2 range
        tlim3 %Joint 3 range
        homeposition %Designated home config.
        baudrate %Arduino baudrate
        tpin %Transducer trig pin
        epin %Transducer echo pin
        pwm1 %Joint 1 pulse width modulation range
        pwm2 %Joint 2 pulse width modulation range
        pwm3 %Joint 3 pulse width modulation range
        theta1 %Joint 1 angle value
        seektraj %Hard-coded seek trajectory
        j1 %Joint 1 angle value
        j2 %Joint 2 angle value
        j3 %Joint 3 angle value
        angmat %Joint angle matrix      
        
        %Serial Data Containers 
        linedata %Incoming arduino data
        pointdata %Transducer point distance data
        distdata %Transducer trajectory distance data
 
        %GUI/Arm Status Values
        errorStatus = 1;
        status_vals = {"Error Status: Nominal","Error Status: Potential Collision",...
                           "Error Status: Workspace Boundary Limit",...
                           "Error Status: Singularity"};
        status_color = ['k', 'r', 'r', 'r'];
        statuslamp = ['g','r','r','y'];
        checkboxlist = [0,0,0,0,0,0];
        vistatus = 0; %Computer vision status 
        abtstatus = 0; %Abort status

        %Main App Objects
        arm %Robotic arm 
        box %Detection box
        boxdim %Dimensions of detection box
        cam %Ipcamera
        arduino %Arduino Mega

        %Action-Created UI Components 
        DisconnectButton
        COMPortLabel
        COMPortEditField
        InjectionConfirmationPanel
        UIAxes2
        InjectButton
        AbortButton
        CancelButton
    end
    
    methods (Access = private)
        %Initialize IPCamera
        function ipcamerastart(app)
            app.cam = ipcam('http://192.168.0.212:81/','Timeout',10);
            hImage = image(app.UIAxes,zeros(720,1280,3));
            app.UIAxes.XLim = [0,1280];
            app.UIAxes.YLim = [0,720];
            pbaspect(app.UIAxes,[1280,720,1]);
            preview(app.cam,hImage)
        end
        
        %Delete the IPCamera Upon Camera Request
        function ipcamerastop(app)
            closePreview(app.cam)
            delete(app.cam)
        end

        %Look For and Draw Detection Box 
        function computervis(app)
            delete(app.box);
            img = snapshot(app.cam);
            datum = im2gray(imread("images/datum.png"));
            scene = im2gray(img); %Importing scene image and changing to 2D (grayscale)

            %Finding Locations of Matching Points b/w Images____________________________
            datumPoints = detectSURFFeatures(datum); %Collects points of interest
            scenePoints = detectSURFFeatures(scene); %Collects points of interest
            [datumFeatures,datumPoints] = extractFeatures(datum,datumPoints); %Returns extracted feature vectors, also known as descriptors, and their corresponding locations
            [sceneFeatures,scenePoints] = extractFeatures(scene,scenePoints); %Returns extracted feature vectors, also known as descriptors, and their corresponding locations
            datumPairs = matchFeatures(datumFeatures, sceneFeatures); %Returns indices of the matching features in the two input feature sets
            matcheddatumPoints = datumPoints(datumPairs(:,1),:); %Respective locations of points of interest
            matchedscenePoints = scenePoints(datumPairs(:,2),:);
            
            %Creation of Polygon Surrounding Datum via Transformation__________________
            try
                [tform,~] = estgeotform2d(matcheddatumPoints, matchedscenePoints, 'affine'); % estimates a 2-D geometric transformation between two images by mapping the inliers in the matched points from one image to another
            catch
                app.vistatus = 1;
                tform = [];
            end
            if ~isempty(tform)
            datumPolygon = [1,1; size(datum,2),1; size(datum,2),size(datum,1); size(datum,2),size(datum,1); 1,size(datum,1); 1,1];
            newdatumPolygon = transformPointsForward(tform, datumPolygon);
                if size(matchedscenePoints,1) > 0
                    hold(app.UIAxes,'on')
                    app.box = line(app.UIAxes,newdatumPolygon(:,1),newdatumPolygon(:,2),Color='r',LineWidth = 2);
                    hold(app.UIAxes,'off')
                    app.boxdim = newdatumPolygon;
                    app.vistatus = 0;
                else
                end 
            end
        end

        %Creates Rigid Body Tree Model of Robot 
        function arm_model(app,theta1,theta2,theta3)
            theta1 = deg2rad(theta1);
            theta2 = deg2rad(theta2);
            theta3 = deg2rad(theta3);
            
            orig = rigidBody('orig');
            tform = trvec2tform([0,0,0]); 
            setFixedTransform(orig.Joint,tform); 

            link1 = rigidBody('link1');
            joint1 = rigidBodyJoint('joint1','revolute');
            joint1.JointAxis = [0,0,-1];
            joint1.HomePosition = deg2rad(theta1);
            tform1 = trvec2tform([0,0,0]); 
            setFixedTransform(joint1,tform1);
            link1.Joint = joint1;

            link2 = rigidBody('link2');
            joint2 = rigidBodyJoint('joint2','revolute');
            joint2.JointAxis = [0,-1,0];
            joint2.HomePosition = deg2rad(theta2);
            tform2 = trvec2tform([0,0,app.d]); 
            setFixedTransform(joint2,tform2);
            link2.Joint = joint2;

            link3 = rigidBody('link3');
            joint3 = rigidBodyJoint('joint3','revolute');
            joint3.JointAxis = [0,1,0];
            joint3.HomePosition = deg2rad(theta3);
            tform3 = trvec2tform([app.l2,0,0]);
            setFixedTransform(joint3,tform3);
            link3.Joint = joint3;

            ee = rigidBody('endeffector');
            tform4 = trvec2tform([app.l3,0,0]); 
            setFixedTransform(ee.Joint,tform4);

            base_transform = [1,0,0,0; 0,1,0,0; 0,0,1,app.d; 0,0,0,1];
            ee_transform = [1,0,0,-app.l3+42; 0,1,0,0; 0,0,1,0; 0,0,0,1];
            addVisual(orig,'mesh',{'3d models/base.stl',[1,1,1]});
            addVisual(link1,'mesh',{'3d models/link1.stl',[1,1,1]},base_transform);
            addVisual(link2,'mesh',{'3d models/link2.stl',[1,1,1]});
            addVisual(link3,'mesh',{'3d models/link3.stl',[1,1,1]});
            addVisual(ee,'mesh',{'3d models/ee.stl',[1,1,1]},ee_transform);

            Arm = rigidBodyTree;
            addBody(Arm,orig,'base');
            addBody(Arm,link1,'orig');
            addBody(Arm,link2,'link1');
            addBody(Arm,link3,'link2');
            addBody(Arm,ee,'link3');
            app.arm = Arm;
        end

        %Plots current orientation of robot
        function arm_plot(app,theta1,theta2,theta3)
            config = homeConfiguration(app.arm);
            config(1).JointPosition = deg2rad(theta1);
            config(2).JointPosition = deg2rad(theta2);
            config(3).JointPosition = deg2rad(theta3);
            ax1 = axes(app.RobotOrientationPanel);
            show(app.arm,config,"Parent",ax1,"Visuals","on"); 
            ax1.XLim = [-300,300];
            ax1.YLim = [-300,300];
            ax1.ZLim = [0,300];
            %Need a way to delete objects (causing lag)!
        end

        %Command Robot to Single Point
        function SendPoint(app,type,M)
            clear app.pointdata
            data = [app.pwm1(1),app.pwm1(2),app.tlim1(1),app.tlim1(2),app.pwm2(1),app.pwm2(2),...
            app.tlim2(1),app.tlim2(2),app.pwm3(1),app.pwm3(2),app.tlim3(1),app.tlim3(2)]; %Config data
            %PWM values interpolated from config data and provided angles
            PWM1 = round(interp1([data(3),data(4)],[data(1),data(2)],M(1))); 
            PWM2 = round(interp1([data(7),data(8)],[data(5),data(6)],M(2)));
            PWM3 = round(interp1([data(11),data(12)],[data(9),data(10)],M(3)));
            %Conversion to strings
            PWM1 = num2str(PWM1,'%03i');
            PWM2 = num2str(PWM2,'%03i');
            PWM3 = num2str(PWM3,'%03i');
            %Update arm plot
            arm_plot(app,M(1),M(2),M(3))
            %Either send/recieve or just send data to arduino
            if str2double(type) == 3
                sendString = append(type,PWM1,PWM2,PWM3);
                app.arduino.writeline(sendString)
                app.pointdata = str2double(app.linedata);
            else
                sendString = append(type,PWM1,PWM2,PWM3);
                app.arduino.writeline(sendString)
            end
            flush(app.arduino)
        end

        %Command Robot Along a Trajectory
        function SendTraj(app,type,int,M)
            clc
            clear app.linedata app.distdata dist 
            flush(app.arduino)
            data = [app.pwm1(1),app.pwm1(2),app.tlim1(1),app.tlim1(2),app.pwm2(1),app.pwm2(2),...
            app.tlim2(1),app.tlim2(2),app.pwm3(1),app.pwm3(2),app.tlim3(1),app.tlim3(2)];
            Q = [round(interp1([data(3),data(4)],[data(1),data(2)],M(1,:))); round(interp1([data(7),data(8)],[data(5),data(6)],M(2,:)));...
                round(interp1([data(11),data(12)],[data(9),data(10)],M(3,:)))];
            dist = zeros(1,size(M,2));
            if str2double(type) == 3
                for i = 1:size(Q,2)
                    sendString = append(type,num2str(Q(1,i),'%03i'),num2str(Q(2,i),'%03i'),num2str(Q(3,i),'%03i'));
                    app.arduino.writeline(sendString)
                    if str2double(app.linedata) > 14 || str2double(app.linedata) < 4.5
                        dist(i) = NaN;
                    else
                        dist(i) = str2double(app.linedata);
                    end
                    if i > 100
                        dist = dist(~isnan(dist));
                        dist = nonzeros(dist);
                        range = mean(dist(1:16));
                        if mean(dist(end-10:end)) < range-1.1
                            app.theta1 = M(1,i);
                            app.distdata = dist;
                            flush(app.arduino)
                            break
                        end
                    end                   
                    pause(int)    
                    flush(app.arduino)
                    if app.abtstatus == 1
                        break
                    end
                end
            else
                for i = 1:size(Q,2)
                    sendString = append(type,num2str(Q(1,i),'%03i'),num2str(Q(2,i),'%03i'),num2str(Q(3,i),'%03i'));
                    app.arduino.writeline(sendString) 
                    pause(int)    
                    flush(app.arduino)
                    if app.abtstatus == 1
                        break
                    end
                end
            end
        end
        
        %Run Seek Trajectory, Create and Interpret Resulting Height Map, Locate Datum
        function SeekTraj(app)
            %Send initial point, pause, send trajectory
            SendPoint(app,'2',[app.seektraj(1,1),app.seektraj(2,1),app.seektraj(3,1)])
            pause(0.5)
            SendTraj(app,'3',0.025,app.seektraj)
            %Recover angles and associated distance data / find peaks
            try
                J1 = app.theta1;
                J2 = 60;
                J3 = 130;
                SendPoint(app,'2',[J1,J2,J3])
                pause(1)
                computervis(app)
                pause(2)
                M = locate(app,J1,J2,J3);
                polys = polyshape(app.boxdim(:,1),app.boxdim(:,2));
                if app.abtstatus == 0
                    center(app,polys,M)
                    injection(app)
                end
            catch
                SendPoint(app,'2',[app.homeposition(1),app.homeposition(2),app.homeposition(3)])
            end
        end

        %Attempts to Locate Datum
        function M = locate(app,J1,J2,J3)
            i = 0;
            ii = 0;
            iii = 0;
            if app.vistatus == 1
                SendPoint(app,'3',[J1,J2,J3])
                while app.pointdata < 0 
                    i = i+0.5;
                    SendPoint(app,'3',[J1+i,J2,J3])  
                    if app.abtstatus == 1
                        break
                    end
                end
                distmat(1) = app.pointdata;
                pause(1)
                SendPoint(app,'3',[J1+15,J2,J3])
                while app.pointdata < 0 
                    ii = ii+0.5;
                    SendPoint(app,'3',[J1+15+ii,J2,J3])   
                    if app.abtstatus == 1
                        break
                    end
                end
                distmat(2) = app.pointdata;
                pause(1)
                SendPoint(app,'3',[J1-15,J2,J3])
                while app.pointdata < 0 
                    iii = iii+0.5;
                    SendPoint(app,'3',[J1-15-iii,J2,J3])  
                    if app.abtstatus == 1
                        break
                    end
                end
                distmat(3) = app.pointdata;
                pause(1)
                I = find(distmat==min(distmat,[],'all'));
                i = 0;
                if I == 2
                    anglechange = 1;
                else
                    anglechange = -1;
                end
                while app.vistatus == 1
                    i = i+anglechange;
                    J1 = J1+i;
                    SendPoint(app,'2',[J1,J2,J3])
                    pause(2)
                    computervis(app)
                    pause(1)
                    if app.abtstatus == 1
                        break
                    end
                end
            end
            M = [J1,J2,J3];
        end

        %Change Joint Angles to Center Detection Box
        function center(app,poly,M)
            app.j1 = M(1);
            app.j2 = M(2);
            app.j3 = M(3);
            [cx,cy] = centroid(poly);
            i = 1;
            ii = 1;
            while cx > 655
                i = i+1;
                app.j1 = app.j1-i;
                SendPoint(app,'2',[app.j1,app.j2,app.j3])
                pause(1)
                computervis(app)
                pause(2)
                while app.vistatus == 1
                    ii = ii+0.5;
                    app.j1 = app.j1+ii;
                    SendPoint(app,'2',[app.j1,app.j2,app.j3])
                    pause(1)
                    computervis(app)
                    pause(2)
                    if app.abtstatus == 1
                        break
                    end
                end
                polys = polyshape(app.boxdim(:,1),app.boxdim(:,2));
                [cx,cy] = centroid(polys);
                if app.abtstatus == 1
                    break
                end
            end
            i = 1;
            ii = 1;
            while cx < 625
                i = i+1;
                app.j1 = app.j1+i;
                SendPoint(app,'2',[app.j1,app.j2,app.j3])
                pause(1)
                computervis(app)
                pause(2)
                while app.vistatus == 1
                    ii = ii+0.5;
                    app.j1 = app.j1-ii;
                    SendPoint(app,'2',[app.j1,app.j2,app.j3])
                    pause(1)
                    computervis(app)
                    pause(2)
                    if app.abtstatus == 1
                        break
                    end
                end
                polys = polyshape(app.boxdim(:,1),app.boxdim(:,2));
                [cx,cy] = centroid(polys);
                if app.abtstatus == 1
                    break
                end
            end
            i = 1;
            ii = 1;
            while cy > 375
                i = i+1;
                app.j3 = app.j3-i;
                SendPoint(app,'2',[app.j1,app.j2,app.j3])
                pause(1)
                computervis(app)
                pause(2)
                while app.vistatus == 1
                    ii = ii+0.5;
                    app.j3 = app.j3+ii;
                    SendPoint(app,'2',[app.j1,app.j2,app.j3])
                    pause(1)
                    computervis(app)
                    pause(2)
                    if app.abtstatus == 1
                        break
                    end
                end
                polys = polyshape(app.boxdim(:,1),app.boxdim(:,2));
                [~,cy] = centroid(polys);
                if app.abtstatus == 1
                    break
                end
            end
            i = 1;
            ii = 1;
            while cy < 345
                i = i+1;
                app.j3 = app.j3+i;
                SendPoint(app,'2',[app.j1,app.j2,app.j3])
                pause(1)
                computervis(app)
                pause(2)
                while app.vistatus == 1
                    ii = ii+0.5;
                    app.j3 = app.j3-ii;
                    SendPoint(app,'2',[app.j1,app.j2,app.j3])
                    pause(1)
                    computervis(app)
                    pause(2)
                    if app.abtstatus == 1
                        break
                    end
                end
                polys = polyshape(app.boxdim(:,1),app.boxdim(:,2));
                [~,cy] = centroid(polys);
                if app.abtstatus == 1
                    break
                end
            end
        end

        %Review Height Map, Distance, Create and Send Injection Trajectory
        function injection(app)
            app.InjectionConfirmationPanel = uipanel(app.ControlTab);
            app.InjectionConfirmationPanel.Title = 'Injection Confirmation';
            app.InjectionConfirmationPanel.Position = [18 43 479 556];
            app.UIAxes2 = uiaxes(app.InjectionConfirmationPanel);
            title(app.UIAxes2, 'Height Map')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Position = [27 190 417 330];
            app.InjectButton = uibutton(app.InjectionConfirmationPanel, 'push');
            app.InjectButton.ButtonPushedFcn = createCallbackFcn(app, @InjectButtonPushed, false);
            app.InjectButton.BackgroundColor = [1 0 0];
            app.InjectButton.FontColor = [1 1 1];
            app.InjectButton.Position = [190 87 100 23];
            app.InjectButton.Text = 'Inject';
            app.CancelButton = uibutton(app.InjectionConfirmationPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, false);
            app.CancelButton.BackgroundColor = [0 1 0];
            app.CancelButton.FontColor = [0 0 0];
            app.CancelButton.Position = [190 57 100 23];
            app.CancelButton.Text = 'Cancel';
            plot(app.UIAxes2,1:1:size(app.distdata,1),app.distdata,'.k','MarkerSize',15)
            if app.j1 < 0
                chng = -5;
            else
                chng = 5;
            end
            SendPoint(app,'3',[app.j1+chng,app.j2,app.j3])
            dist = (app.pointdata*10)-2;
            pause(1)
            injint = 0:0.5:(dist-37);
            J1 = zeros(1,size(injint,2));
            J2 = zeros(1,size(injint,2));
            J3 = zeros(1,size(injint,2));
            for i = 1:size(injint,2)
                [x,y,z] = forw_kin(app,app.j1,app.j2,app.j3,app.d,app.l2,app.l3+injint(i));
                [t1,t2,t3] = inv_kin(app,app.d,app.l2,app.l3,x,y,z);
                J1(i) = t1;
                J2(i) = t2;
                J3(i) = t3;
            end
            app.angmat = [J1+5; J2; J3-1];
        end
        
        %Proceed with Injection
        function InjectButtonPushed(app)
            app.abtstatus = 0;
            reverse = flip(app.angmat,2);
            delete(app.box);
            SendTraj(app,'2',0.025,app.angmat)
            pause(1)
            SendTraj(app,'2',0.025,reverse)            
            SendPoint(app,'2',[app.homeposition(1),app.homeposition(2),app.homeposition(3)])
            delete(app.InjectionConfirmationPanel)
            AbortButtonPushed(app)
        end

        %Computes Forward Kinematics of Robot
        function [x,y,z] = forw_kin(~,q1,q2,q3,D,L2,L3)
            q1 = deg2rad(q1);
            q2 = deg2rad(q2);
            q3 = deg2rad(q3);
            x = cos(q1)*((L2*cos(q2))+(L3*cos(q2-q3)));
            y = sin(q1)*((L2*cos(q2))+(L3*cos(q2-q3)));
            z = D+(L2*sin(q2))+(L3*sin(q2-q3));
        end

        %Computes Inverse Kinematics of Robot
        function [theta1,theta2,theta3] = inv_kin(~,D,L2,L3,X,Y,Z)
            R = sqrt((X^2)+(Y^2));
            L = sqrt((R^2)+((Z-D)^2));
            theta1 = rad2deg(atan2(Y,X));
            theta2 = rad2deg(atan2(Z-D,R)+acos(((L2^2)+(L^2)-(L3^2))/(2*L2*L)));
            theta3 = rad2deg(pi-acos(((L3^2)+(L2^2)-(L^2))/(2*L2*L3)));
        end

        %Disconnect from Arduino
        function DisconnectButtonPushed(app)
            delete(app.arduino)
            delete(app.DisconnectButton)
            app.Label.Text = '';
            app.Label_2.Text = '';
            app.PortLamp.Color = 'w';
            app.ArmLamp.Color = 'w';
            app.ConnectButton.Enable = "on";
        end

        %Abort Seek Trajectory
        function AbortButtonPushed(app)
            app.abtstatus = 1; 
            delete(app.box)
            SendPoint(app,'2',[app.homeposition(1),app.homeposition(2),app.homeposition(3)])
            delete(app.AbortButton)
            app.GoHomeButton.Enable = 'on';
            app.SeekButton.Enable = 'on';
        end

        %Abort Injection Trajectory / Close Injection Panel
        function CancelButtonPushed(app)
            app.abtstatus = 1; 
            SendPoint(app,'2',[app.homeposition(1),app.homeposition(2),app.homeposition(3)])
            delete(app.box)
            delete(app.InjectionConfirmationPanel)
            AbortButtonPushed(app)
        end

        %Save User Saved Configuration Info
        function saveState(app)
            state.pwm1 = app.pwm1;
            state.pwm2 = app.pwm2;
            state.pwm3 = app.pwm3;
            state.d = app.d;
            state.l2 = app.l2;
            state.l3 = app.l3;
            state.tlim1 = app.tlim1;
            state.tlim2 = app.tlim2;
            state.tlim3 = app.tlim3;
            state.homeposition = app.homeposition;
            state.baudrate = app.baudrate;
            state.tpin = app.tpin;
            state.epin = app.epin;
            save('PWMvals.mat','state');
        end

        %Load User Saved Configuration Info
        function loadState(app)
            load('PWMvals.mat','state');
            app.pwm1 = state.pwm1;
            app.pwm2 = state.pwm2;
            app.pwm3 = state.pwm3;
            app.d = state.d;
            app.l2 = state.l2;
            app.l3 = state.l3;
            app.tlim1 = state.tlim1;
            app.tlim2 = state.tlim2;
            app.tlim3 = state.tlim3;
            app.homeposition = state.homeposition;
            app.baudrate = state.baudrate;
            app.tpin = state.tpin;
            app.epin = state.epin;
        end

        %Read Incoming Data From Arduino%
        function readString(app,src,~)
            app.linedata = readline(src);
            app.SerialDataTextArea.Value = app.linedata;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            %Look For User Saved Variable Data and Initialize Default if N/A
            try
                app.loadState;
            catch
                app.d = 110;
                app.l2 = 105;
                app.l3 = 140;
                app.tlim1 = [-90,90];
                app.tlim2 = [0,90];
                app.tlim3 = [0,135];
                app.homeposition = [0,20,40];
                app.baudrate = 115200;
                app.tpin = 22;
                app.epin = 23;
                app.pwm1 = [488,112]; 
                app.pwm2 = [96,276];
                app.pwm3 = [112,396]; 
            end
   
            %Initialize Seek Trajectory
            q1 = -60:2:60;
            q2 = 55;
            q3 = [120:1:130,130:-1:120];
            N = size(q1,2)*(size(q3,2)/2);
            app.seektraj = [repelem(q1,size(q3,2)/2); repmat(q2,1,N); repmat(q3,1,round(N/size(q3,2))-1),q3(1:size(q3,2)/2)];

            %Updating UI Representation of Variable Data
            app.Link1d1EditField.Value = app.d;
            app.Link2L2EditField.Value = app.l2;
            app.Link3L3EditField.Value = app.l3;
            app.MinEditField.Value = app.tlim1(1);
            app.MaxEditField.Value = app.tlim1(2);
            app.MinEditField_2.Value = app.tlim2(1);
            app.MaxEditField_2.Value = app.tlim2(2);
            app.MinEditField_3.Value = app.tlim3(1);
            app.MaxEditField_3.Value = app.tlim3(2);
            app.q1EditField.Value = app.homeposition(1);
            app.q2EditField.Value = app.homeposition(2);
            app.q3EditField.Value = app.homeposition(3);
            app.BaudRateEditField.Value = app.baudrate;
            app.TrigPinEditField.Value = app.tpin;
            app.EchoPinEditField.Value = app.epin;
            app.Label_3.Text = append(num2str(app.tlim1(1)),'°');
            app.Label_4.Text = append(num2str(app.tlim1(2)),'°');
            app.Label_5.Text = append(num2str(app.tlim2(1)),'°');
            app.Label_6.Text = append(num2str(app.tlim2(2)),'°');
            app.Label_7.Text = append(num2str(app.tlim3(1)),'°');
            app.Label_8.Text = append(num2str(app.tlim3(2)),'°');
            app.PortLamp.Color = 'w';
            app.ArmLamp.Color = 'w';

            %Find Available Serial Ports 
            delete(serialportfind);
            spl = serialportlist("all");

            % Create Arduino Communication Specific UI Components
            app.COMPortLabel = uilabel(app.ArduinoConnectionKinematicStatusPanel);
            app.COMPortLabel.HorizontalAlignment = 'right';
            app.COMPortLabel.Position = [12 93 64 22];
            app.COMPortLabel.Text = 'Serial Port:';
            app.COMPortEditField = uidropdown(app.ArduinoConnectionKinematicStatusPanel,"Items",spl);
            app.COMPortEditField.Position = [91 93 100 22];        
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            %Initializes Connection to Arduino & Sets Parameters
            app.ConnectButton.Enable = "off";
            app.PortLamp.Color = 'y';
            try 
                app.arduino = serialport(app.COMPortEditField.Value,app.baudrate); % no modification
            catch
                app.arduino = serialport(convertCharsToStrings(app.COMPortEditField.Value),app.baudrate); % text modified
            end
            
            configureTerminator(app.arduino,'CR/LF');       
            flush(app.arduino);
            configureCallback(app.arduino,"terminator",@(varargin) readString(app,varargin{1}));
            app.arduino.InputBufferSize = 100;

            %Updating UI, Functionality, and Initializing Robot
            dots = '...................';
            app.Label.Text = 'Connecting';
            pause(.5);
            for i = 1:19
                app.Label.Text = ['Connecting' dots(1:i)];
                pause(.1);
            end
            app.PortLamp.Color = 'g';
            app.Label.Text = 'Connected to Arduino';
            arm_model(app,app.homeposition(1),app.homeposition(2),app.homeposition(3));
            arm_plot(app,app.homeposition(1),app.homeposition(2),app.homeposition(3));
            app.ArmLamp.Color = app.statuslamp(app.errorStatus);
            app.Label_2.Text = app.status_vals{app.errorStatus}; 
            app.DisconnectButton = uibutton(app.ArduinoConnectionKinematicStatusPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, false);
            app.DisconnectButton.BackgroundColor = [1 0 0];
            app.DisconnectButton.Position = [259 93 100 23];
            app.DisconnectButton.Text = 'Disconnect';
            app.SeekButton.Enable = 'on';
            app.Switch.Enable = 'on';
            app.GoHomeButton.Enable = 'on';

            %Send Home Configuration Command & Start ipcamera
            SendPoint(app,'2',[app.homeposition(1),app.homeposition(2),app.homeposition(3)])
            ipcamerastart(app)
        end

        % Button pushed function: SeekButton
        function SeekButtonPushed(app, event)
            %Initializes seektraj Function
            app.abtstatus = 0;
            app.SeekButton.Enable = 'off';
            app.GoHomeButton.Enable = 'off';
            app.AbortButton = uibutton(app.ControlTab, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, false);
            app.AbortButton.BackgroundColor = [1 0 0];
            app.AbortButton.Position = [277 43 100 23];
            app.AbortButton.Text = 'Abort';
            SeekTraj(app) 
        end

        % Close request function: InjectionControlAppUIFigure
        function InjectionControlAppUIFigureCloseRequest(app, event)
            %Deletes ipcam Object Upon Close Request to Prevent MATLAB from Crashing
            try
                ipcamerastop(app)
            catch
                delete(app.cam)
                delete(app)
            end
            delete(app)
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            %Saves & Updates Arm Configuration Data
            app.d = app.Link1d1EditField.Value;
            app.l2 = app.Link2L2EditField.Value;
            app.l3 = app.Link3L3EditField.Value;
            app.tlim1 = [app.MinEditField.Value,app.MaxEditField.Value];
            app.tlim2 = [app.MinEditField_2.Value,app.MaxEditField_2.Value];
            app.tlim3 = [app.MinEditField_3.Value,app.MaxEditField_3.Value];
            app.homeposition = [app.q1EditField.Value,app.q2EditField.Value,app.q3EditField.Value];
            app.Label_3.Text = append(num2str(app.tlim1(1)),'°');
            app.Label_4.Text = append(num2str(app.tlim1(2)),'°');
            app.Label_5.Text = append(num2str(app.tlim2(1)),'°');
            app.Label_6.Text = append(num2str(app.tlim2(2)),'°');
            app.Label_7.Text = append(num2str(app.tlim3(1)),'°');
            app.Label_8.Text = append(num2str(app.tlim3(2)),'°');
            app.saveState;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            %Allows for PWM Configuration
            switchpos = app.Switch.Value;
            if switchpos == "On"
                app.q1PulseWidthSpinner.Enable = 'on';
                app.q2PulseWidthSpinner.Enable = 'on';
                app.q3PulseWidthSpinner.Enable = 'on';
                app.MinPulseWidthCheckBox.Enable = 'on';
                app.MaxPulseWidthCheckBox.Enable = 'on';
                app.MinPulseWidthCheckBox_2.Enable = 'on';
                app.MaxPulseWidthCheckBox_2.Enable = 'on';
                app.MinPulseWidthCheckBox_3.Enable = 'on';
                app.MaxPulseWidthCheckBox_3.Enable = 'on';
                app.SubmitButton.Enable = 'on';
            else
                app.q1PulseWidthSpinner.Enable = 'off';
                app.q2PulseWidthSpinner.Enable = 'off';
                app.q3PulseWidthSpinner.Enable = 'off';
                app.MinPulseWidthCheckBox.Enable = 'off';
                app.MaxPulseWidthCheckBox.Enable = 'off';
                app.MinPulseWidthCheckBox_2.Enable = 'off';
                app.MaxPulseWidthCheckBox_2.Enable = 'off';
                app.MinPulseWidthCheckBox_3.Enable = 'off';
                app.MaxPulseWidthCheckBox_3.Enable = 'off';
                app.SubmitButton.Enable = 'off';
            end
        end

        % Value changed function: MinPulseWidthCheckBox
        function MinPulseWidthCheckBoxValueChanged(app, event)
            %Updates Min PWM Value for Joint 1
            value = app.MinPulseWidthCheckBox.Value;
            if value == 1
                app.pwm1(1) = app.q1PulseWidthSpinner.Value;
                app.MinPulseWidthCheckBox.Enable = 'off';
                app.checkboxlist(1) = 1;
            else
            end
        end

        % Value changed function: MaxPulseWidthCheckBox
        function MaxPulseWidthCheckBoxValueChanged(app, event)
            %Updates Max PWM Value for Joint 1
            value = app.MaxPulseWidthCheckBox.Value;
            if value == 1
                app.pwm1(2) = app.q1PulseWidthSpinner.Value;
                app.MaxPulseWidthCheckBox.Enable = 'off';
                app.checkboxlist(2) = 1;
            else
            end
        end

        % Value changed function: MinPulseWidthCheckBox_2
        function MinPulseWidthCheckBox_2ValueChanged(app, event)
            %Updates Min PWM Value for Joint 2
            value = app.MinPulseWidthCheckBox_2.Value;
            if value == 1
                app.pwm2(1) = app.q2PulseWidthSpinner.Value;
                app.MinPulseWidthCheckBox_2.Enable = 'off';
                app.checkboxlist(3) = 1;
            else
            end
        end

        % Value changed function: MaxPulseWidthCheckBox_2
        function MaxPulseWidthCheckBox_2ValueChanged(app, event)
            %Updates Max PWM Value for Joint 2
            value = app.MaxPulseWidthCheckBox_2.Value;
            if value == 1
                app.pwm2(2) = app.q2PulseWidthSpinner.Value;
                app.MaxPulseWidthCheckBox_2.Enable = 'off';
                app.checkboxlist(4) = 1;
            else
            end
        end

        % Value changed function: MinPulseWidthCheckBox_3
        function MinPulseWidthCheckBox_3ValueChanged(app, event)
            %Updates Min PWM Value for Joint 3
            value = app.MinPulseWidthCheckBox_3.Value;
            if value == 1
                app.pwm3(1) = app.q3PulseWidthSpinner.Value;
                app.MinPulseWidthCheckBox_3.Enable = 'off';
                app.checkboxlist(5) = 1;
            else
            end
        end

        % Value changed function: MaxPulseWidthCheckBox_3
        function MaxPulseWidthCheckBox_3ValueChanged(app, event)
            %Updates Max PWM Value for Joint 3
            value = app.MaxPulseWidthCheckBox_3.Value;
            if value == 1
                app.pwm3(2) = app.q3PulseWidthSpinner.Value;
                app.MaxPulseWidthCheckBox_3.Enable = 'off';
                app.checkboxlist(6) = 1;
            else
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            %Submits PWM Configuration Data
            if sum(app.checkboxlist) == 6
                app.saveState;
            else
            end
        end

        % Value changed function: q1PulseWidthSpinner
        function q1PulseWidthSpinnerValueChanged(app, event)
            %Changes Angle of Joint 1 for PWM Configuration
            pwm = app.q1PulseWidthSpinner.Value;
            flush(app.arduino);
            app.arduino.writeline(append('1','1',num2str(pwm)));
            flush(app.arduino);
        end

        % Value changed function: q2PulseWidthSpinner
        function q2PulseWidthSpinnerValueChanged(app, event)
            %Changes Angle of Joint 2 for PWM Configuration
            pwm = app.q2PulseWidthSpinner.Value;
            flush(app.arduino);
            app.arduino.writeline(append('1','2',num2str(pwm)));
            flush(app.arduino);
        end

        % Value changed function: q3PulseWidthSpinner
        function q3PulseWidthSpinnerValueChanged(app, event)
            %Changes Angle of Joint 3 for PWM Configuration
            pwm = app.q3PulseWidthSpinner.Value;
            flush(app.arduino);
            app.arduino.writeline(append('1','3',num2str(pwm)));
            flush(app.arduino);
        end

        % Button pushed function: EnterButton_2
        function EnterButton_2Pushed(app, event)
            %Saves Arduino Configuration Data
            app.baudrate = app.BaudRateEditField.Value;
            app.tpin = app.TrigPinEditField.Value;
            app.epin = app.EchoPinEditField.Value;
            app.saveState;
        end

        % Button pushed function: GoHomeButton
        function GoHomeButtonPushed(app, event)
            %Sends Robot to Home Configuration
            SendPoint(app,'2',[app.homeposition(1),app.homeposition(2),app.homeposition(3)])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.InjectionControlAppUIFigure = uifigure('Visible', 'off');
            app.InjectionControlAppUIFigure.Name = 'Injection Control App';
            app.InjectionControlAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @InjectionControlAppUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.InjectionControlAppUIFigure);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.UIAxes = uiaxes(app.ControlTab);
            title(app.UIAxes, 'Live Camera Feed')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.SerialDataTextAreaLabel = uilabel(app.ControlTab);
            app.SerialDataTextAreaLabel.Text = 'Serial Data';
            app.SerialDataTextArea = uitextarea(app.ControlTab);
            app.ArduinoConnectionKinematicStatusPanel = uipanel(app.ControlTab);
            app.ArduinoConnectionKinematicStatusPanel.Title = 'Arduino Connection & Kinematic Status';
            app.Label = uilabel(app.ArduinoConnectionKinematicStatusPanel);
            app.Label.Text = '';
            app.ConnectButton = uibutton(app.ArduinoConnectionKinematicStatusPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Label_2 = uilabel(app.ArduinoConnectionKinematicStatusPanel);
            app.Label_2.Text = '';
            app.PortLampLabel = uilabel(app.ArduinoConnectionKinematicStatusPanel);
            app.PortLampLabel.Text = 'Port';
            app.PortLamp = uilamp(app.ArduinoConnectionKinematicStatusPanel);
            app.ArmLampLabel = uilabel(app.ArduinoConnectionKinematicStatusPanel);
            app.ArmLampLabel.Text = 'Arm';
            app.ArmLamp = uilamp(app.ArduinoConnectionKinematicStatusPanel);
            app.RobotOrientationPanel = uipanel(app.ControlTab);
            app.RobotOrientationPanel.Title = 'Robot Orientation';
            app.SeekButton = uibutton(app.ControlTab, 'push');
            app.SeekButton.ButtonPushedFcn = createCallbackFcn(app, @SeekButtonPushed, true);
            app.SeekButton.Enable = 'off';
            app.SeekButton.Text = 'Seek';
            app.GoHomeButton = uibutton(app.ControlTab, 'push');
            app.GoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @GoHomeButtonPushed, true);
            app.GoHomeButton.Enable = 'off';
            app.GoHomeButton.Text = 'Go Home';
            app.ConfigureTab = uitab(app.TabGroup);
            app.ConfigureTab.Title = 'Configure';
            app.ArmConfigurationPanel = uipanel(app.ConfigureTab);
            app.ArmConfigurationPanel.Title = 'Arm Configuration';
            app.Image = uiimage(app.ArmConfigurationPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'Screenshot 2024-11-15 at 1.43.06 PM.png');
            app.ArmLayoutVariablesandConventionsLabel = uilabel(app.ArmConfigurationPanel);
            app.ArmLayoutVariablesandConventionsLabel.Text = 'Arm Layout, Variables and Conventions';
            app.LinkLengthsmmLabel = uilabel(app.ArmConfigurationPanel);
            app.LinkLengthsmmLabel.Text = 'Link Lengths (mm)';
            app.JointAngleLimitsdegLabel = uilabel(app.ArmConfigurationPanel);
            app.JointAngleLimitsdegLabel.Text = 'Joint Angle Limits (deg)';
            app.q1Label = uilabel(app.ArmConfigurationPanel);
            app.q1Label.Text = 'q1)';
            app.q2Label = uilabel(app.ArmConfigurationPanel);
            app.q2Label.Text = 'q2)';
            app.q3Label = uilabel(app.ArmConfigurationPanel);
            app.q3Label.Text = 'q3)';
            app.Link1d1EditFieldLabel = uilabel(app.ArmConfigurationPanel);
            app.Link1d1EditFieldLabel.Text = 'Link 1 (d1):';
            app.Link1d1EditField = uieditfield(app.ArmConfigurationPanel, 'numeric');
            app.Link1d1EditField.Value = 110;
            app.Link2L2EditFieldLabel = uilabel(app.ArmConfigurationPanel);
            app.Link2L2EditFieldLabel.Text = 'Link 2 (L2):';
            app.Link2L2EditField = uieditfield(app.ArmConfigurationPanel, 'numeric');
            app.Link2L2EditField.Value = 105;
            app.Link3L3EditFieldLabel = uilabel(app.ArmConfigurationPanel);
            app.Link3L3EditFieldLabel.Text = 'Link 3 (L3):';
            app.Link3L3EditField = uieditfield(app.ArmConfigurationPanel, 'numeric');
            app.Link3L3EditField.Value = 140;
            app.MinEditFieldLabel = uilabel(app.ArmConfigurationPanel);
            app.MinEditFieldLabel.Text = 'Min:';
            app.MinEditField = uieditfield(app.ArmConfigurationPanel, 'numeric');
            app.MinEditField.Value = -90;
            app.MaxEditFieldLabel = uilabel(app.ArmConfigurationPanel);
            app.MaxEditFieldLabel.Text = 'Max:';
            app.MaxEditField = uieditfield(app.ArmConfigurationPanel, 'numeric');
            app.MaxEditField.Value = 90;
            app.MinEditField_2Label = uilabel(app.ArmConfigurationPanel);
            app.MinEditField_2Label.Text = 'Min:';
            app.MinEditField_2 = uieditfield(app.ArmConfigurationPanel, 'numeric');
            app.MaxEditField_2Label = uilabel(app.ArmConfigurationPanel);
            app.MaxEditField_2Label.Text = 'Max:';
            app.MaxEditField_2 = uieditfield(app.ArmConfigurationPanel, 'numeric');
            app.MaxEditField_2.Value = 90;
            app.MinEditField_3Label = uilabel(app.ArmConfigurationPanel);
            app.MinEditField_3Label.Text = 'Min:';
            app.MinEditField_3 = uieditfield(app.ArmConfigurationPanel, 'numeric');
            app.MaxEditField_3Label = uilabel(app.ArmConfigurationPanel);
            app.MaxEditField_3Label.Text = 'Max:';
            app.MaxEditField_3 = uieditfield(app.ArmConfigurationPanel, 'numeric');
            app.MaxEditField_3.Value = 135;
            app.EnterButton = uibutton(app.ArmConfigurationPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.HomePositiondegLabel = uilabel(app.ArmConfigurationPanel);
            app.HomePositiondegLabel.Text = 'Home Position (deg)';
            app.q1EditFieldLabel = uilabel(app.ArmConfigurationPanel);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.ArmConfigurationPanel, 'numeric');
            app.q2EditFieldLabel = uilabel(app.ArmConfigurationPanel);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.ArmConfigurationPanel, 'numeric');
            app.q2EditField.Value = 20;
            app.q3EditFieldLabel = uilabel(app.ArmConfigurationPanel);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.ArmConfigurationPanel, 'numeric');
            app.q3EditField.Value = 40;
            app.ArduinoConfigurationPanel = uipanel(app.ConfigureTab);
            app.ArduinoConfigurationPanel.Title = 'Arduino Configuration';
            app.Image2 = uiimage(app.ArduinoConfigurationPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'Arduino-Mega-Pinout.png');
            app.BaudRateEditFieldLabel = uilabel(app.ArduinoConfigurationPanel);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate:';
            app.BaudRateEditField = uieditfield(app.ArduinoConfigurationPanel, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%11.4d';
            app.BaudRateEditField.Value = 115200;
            app.EnterButton_2 = uibutton(app.ArduinoConfigurationPanel, 'push');
            app.EnterButton_2.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_2Pushed, true);
            app.EnterButton_2.Text = 'Enter';
            app.EchoPinEditFieldLabel = uilabel(app.ArduinoConfigurationPanel);
            app.EchoPinEditFieldLabel.Text = 'Echo Pin:';
            app.EchoPinEditField = uieditfield(app.ArduinoConfigurationPanel, 'numeric');
            app.EchoPinEditField.ValueDisplayFormat = '%.0f';
            app.EchoPinEditField.Value = 23;
            app.TrigPinEditFieldLabel = uilabel(app.ArduinoConfigurationPanel);
            app.TrigPinEditFieldLabel.Text = 'Trig Pin:';
            app.TrigPinEditField = uieditfield(app.ArduinoConfigurationPanel, 'numeric');
            app.TrigPinEditField.ValueDisplayFormat = '%.0f';
            app.TrigPinEditField.Value = 22;
            app.MotorConfiguratorPanel = uipanel(app.ConfigureTab);
            app.MotorConfiguratorPanel.Title = 'Motor Configurator ';
            app.Switch = uiswitch(app.MotorConfiguratorPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.q1PulseWidthSpinnerLabel = uilabel(app.MotorConfiguratorPanel);
            app.q1PulseWidthSpinnerLabel.Enable = 'off';
            app.q1PulseWidthSpinnerLabel.Text = 'q1 Pulse Width:';
            app.q1PulseWidthSpinner = uispinner(app.MotorConfiguratorPanel);
            app.q1PulseWidthSpinner.Step = 4;
            app.q1PulseWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @q1PulseWidthSpinnerValueChanged, true);
            app.q1PulseWidthSpinner.Enable = 'off';
            app.q1PulseWidthSpinner.Value = 300;
            app.q2PulseWidthSpinnerLabel = uilabel(app.MotorConfiguratorPanel);
            app.q2PulseWidthSpinnerLabel.Enable = 'off';
            app.q2PulseWidthSpinnerLabel.Text = 'q2 Pulse Width:';
            app.q2PulseWidthSpinner = uispinner(app.MotorConfiguratorPanel);
            app.q2PulseWidthSpinner.Step = 4;
            app.q2PulseWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @q2PulseWidthSpinnerValueChanged, true);
            app.q2PulseWidthSpinner.Enable = 'off';
            app.q2PulseWidthSpinner.Value = 300;
            app.q3PulseWidthSpinnerLabel = uilabel(app.MotorConfiguratorPanel);
            app.q3PulseWidthSpinnerLabel.Enable = 'off';
            app.q3PulseWidthSpinnerLabel.Text = 'q3 Pulse Width:';
            app.q3PulseWidthSpinner = uispinner(app.MotorConfiguratorPanel);
            app.q3PulseWidthSpinner.Step = 4;
            app.q3PulseWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @q3PulseWidthSpinnerValueChanged, true);
            app.q3PulseWidthSpinner.Enable = 'off';
            app.q3PulseWidthSpinner.Value = 300;
            app.MinPulseWidthCheckBox = uicheckbox(app.MotorConfiguratorPanel);
            app.MinPulseWidthCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinPulseWidthCheckBoxValueChanged, true);
            app.MinPulseWidthCheckBox.Enable = 'off';
            app.MinPulseWidthCheckBox.Text = 'Min Pulse Width @';
            app.Label_3 = uilabel(app.MotorConfiguratorPanel);
            app.Label_3.Text = '';
            app.MaxPulseWidthCheckBox = uicheckbox(app.MotorConfiguratorPanel);
            app.MaxPulseWidthCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaxPulseWidthCheckBoxValueChanged, true);
            app.MaxPulseWidthCheckBox.Enable = 'off';
            app.MaxPulseWidthCheckBox.Text = 'Max Pulse Width @';
            app.Label_4 = uilabel(app.MotorConfiguratorPanel);
            app.Label_4.Text = '';
            app.MinPulseWidthCheckBox_2 = uicheckbox(app.MotorConfiguratorPanel);
            app.MinPulseWidthCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @MinPulseWidthCheckBox_2ValueChanged, true);
            app.MinPulseWidthCheckBox_2.Enable = 'off';
            app.MinPulseWidthCheckBox_2.Text = 'Min Pulse Width @';
            app.Label_5 = uilabel(app.MotorConfiguratorPanel);
            app.Label_5.Text = '';
            app.MaxPulseWidthCheckBox_2 = uicheckbox(app.MotorConfiguratorPanel);
            app.MaxPulseWidthCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @MaxPulseWidthCheckBox_2ValueChanged, true);
            app.MaxPulseWidthCheckBox_2.Enable = 'off';
            app.MaxPulseWidthCheckBox_2.Text = 'Max Pulse Width @';
            app.Label_6 = uilabel(app.MotorConfiguratorPanel);
            app.Label_6.Text = '';
            app.MinPulseWidthCheckBox_3 = uicheckbox(app.MotorConfiguratorPanel);
            app.MinPulseWidthCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @MinPulseWidthCheckBox_3ValueChanged, true);
            app.MinPulseWidthCheckBox_3.Enable = 'off';
            app.MinPulseWidthCheckBox_3.Text = 'Min Pulse Width @';
            app.Label_7 = uilabel(app.MotorConfiguratorPanel);
            app.Label_7.Text = '';
            app.MaxPulseWidthCheckBox_3 = uicheckbox(app.MotorConfiguratorPanel);
            app.MaxPulseWidthCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @MaxPulseWidthCheckBox_3ValueChanged, true);
            app.MaxPulseWidthCheckBox_3.Enable = 'off';
            app.MaxPulseWidthCheckBox_3.Text = 'Max Pulse Width @';
            app.Label_8 = uilabel(app.MotorConfiguratorPanel);
            app.Label_8.Text = '';
            app.SubmitButton = uibutton(app.MotorConfiguratorPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Enable = 'off';
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.InjectionControlAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = injection_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InjectionControlAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InjectionControlAppUIFigure)
        end
    end
end